classdef begin_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        CheckTextArea       matlab.ui.control.TextArea
        CheckTextAreaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
         Key_List=string([]);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if length(app.Key_List)==0
                app.Key_List=[app.Key_List key]
                app.CheckTextArea.Value=string(app.Key_List);
            else
                for i=1:length(app.Key_List)
                    if app.Key_List(i)==key
                        break
                    else
                        if i==length(app.Key_List)
                            app.Key_List=[app.Key_List key]
                            app.CheckTextArea.Value=string(app.Key_List);
                        else
                            continue
                        end
                    end 
                end
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            for i=1:length(app.Key_List)
                if app.Key_List(i)==key
                    app.Key_List(i)=[]
                    break
                else
                    continue
                end
            end
            if length(app.Key_List)==0
                app.CheckTextArea.Value="No Keys Pressed"
            else
                app.CheckTextArea.Value=string(app.Key_List);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.CheckTextAreaLabel = uilabel(app.UIFigure);
            app.CheckTextAreaLabel.Text = 'Check';
            app.CheckTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = begin_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end